classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        Panel2         matlab.ui.container.Panel
        Label          matlab.ui.control.Label
        DropDown       matlab.ui.control.DropDown
        DropDownLabel  matlab.ui.control.Label
        Panel          matlab.ui.container.Panel
        GridLayout2    matlab.ui.container.GridLayout
        UIAxes3        matlab.ui.control.UIAxes
        UIAxes2        matlab.ui.control.UIAxes
        UIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        current_image; %
        segmented_image;
        current_time;
    end


    methods (Access = private)
        
        
        function set_ui_axes(app)
            %app.UIAxes.YAxis = 'off';
            %app.UIAxes.XAxis = 'off';
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];

            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];

            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];

            title(app.UIAxes, 'Original Image')
            title(app.UIAxes2, 'Segmented Image')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')

            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
        end       

        function set_dropDown(app)
            options = strings(1,10);
            for i=1:10
                options(i) = "im" + num2str(i);
            end
            app.DropDown.Items = options;
        end
        
        function  include_path(app)
            addpath("lib");
            addpath("images");
            addpath("images/tempelates");
        end

        function segment_image(app)
            img = compartment(app.current_image);
            img = isolate_digits(img);
            app.segmented_image = snip(img);
            imshow(app.segmented_image,'Parent',app.UIAxes2);
            [T,C] = guess_time(app.segmented_image);
            display(T)
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            include_path(app);
            set_dropDown(app);
            set_ui_axes(app);
            path = app.DropDown.Value + ".png";
            app.current_image = imread(path);
            imshow(app.current_image,'Parent',app.UIAxes);
            segment_image(app);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            path = value + ".png";
            app.current_image = imread(path);
            imshow(app.current_image,'Parent',app.UIAxes);
            segment_image(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = 'Panel';
            app.GridLayout2 = uigridlayout(app.Panel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, 'Segmented Image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout2);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.Panel2 = uipanel(app.GridLayout);
            app.Panel2.Title = 'Panel2';
            app.DropDownLabel = uilabel(app.Panel2);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.Panel2);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.Label = uilabel(app.Panel2);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end